classdef ESD_Lab4_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        CurrentPointValuesPanel        matlab.ui.container.Panel
        BallYmEditField                matlab.ui.control.NumericEditField
        BallYmEditFieldLabel           matlab.ui.control.Label
        DepthErrmEditField             matlab.ui.control.NumericEditField
        DepthErrmEditFieldLabel        matlab.ui.control.Label
        DepthTruemEditField            matlab.ui.control.NumericEditField
        DepthTruemEditFieldLabel       matlab.ui.control.Label
        DepthEstmEditField             matlab.ui.control.NumericEditField
        DepthEstmEditFieldLabel        matlab.ui.control.Label
        DisppxEditField                matlab.ui.control.NumericEditField
        DisppxEditFieldLabel           matlab.ui.control.Label
        ZmEditField                    matlab.ui.control.NumericEditField
        ZmEditFieldLabel               matlab.ui.control.Label
        XmEditField                    matlab.ui.control.NumericEditField
        XmEditFieldLabel               matlab.ui.control.Label
        CameraViewPanel                matlab.ui.container.Panel
        RightImageLabel                matlab.ui.control.Label
        LeftImageLabel                 matlab.ui.control.Label
        RightImage                     matlab.ui.control.Image
        LeftImage                      matlab.ui.control.Image
        InputParametersPanel           matlab.ui.container.Panel
        ScaleblenderEditField          matlab.ui.control.NumericEditField
        ScaleblenderEditFieldLabel     matlab.ui.control.Label
        IncrementEditField             matlab.ui.control.NumericEditField
        IncrementEditFieldLabel        matlab.ui.control.Label
        BallEndingDepthEditField       matlab.ui.control.NumericEditField
        BallEndingDepthEditFieldLabel  matlab.ui.control.Label
        BallStartingDepthEditField     matlab.ui.control.NumericEditField
        BallStartingDepthEditFieldLabel  matlab.ui.control.Label
        BallStartingHeightEditField    matlab.ui.control.NumericEditField
        BallStartingHeightEditFieldLabel  matlab.ui.control.Label
        ResolutionRowsEditField        matlab.ui.control.NumericEditField
        ResolutionXLabel               matlab.ui.control.Label
        ResolutionColumnsEditField     matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        FocalLengthmmEditField         matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel    matlab.ui.control.Label
        BaselinemEditField             matlab.ui.control.NumericEditField
        BaselinemEditFieldLabel        matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        DepthErrorUIAxes               matlab.ui.control.UIAxes
        EstVsTrueDepthUIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     % -------------------- Default GUI values --------------------
    app.BallStartingHeightEditField.Value = 5.0;   % ball Z
    app.BallStartingDepthEditField.Value  = 2.0;   % start Y
    app.BallEndingDepthEditField.Value    = 8.0;   % end Y
    app.IncrementEditField.Value          = 1;   % Y step

    app.ScaleblenderEditField.Value       = 1.166; % calibration scale
    app.BaselinemEditField.Value          = 1.2;   % meters
    app.FocalLengthmmEditField.Value      = 43;    % mm

    app.ResolutionColumnsEditField.Value  = 1080;  % width
    app.ResolutionRowsEditField.Value     = 720;   % height

    % -------------------- Clear output fields --------------------
    app.XmEditField.Value             = 0;
    app.ZmEditField.Value             = 0;
    app.BallYmEditField.Value         = 0;
    app.DisppxEditField.Value         = 0;

    app.DepthEstmEditField.Value      = 0;
    app.DepthTruemEditField.Value     = 0;
    app.DepthErrmEditField.Value      = 0;

    % -------------------- Store fixed Blender/camera settings --------------------
    app.UIFigure.UserData.server_ip   = '127.0.0.1';
    app.UIFigure.UserData.server_port = 55001;

    app.UIFigure.UserData.camName = "Camera";
    app.UIFigure.UserData.camX0   = 0;
    app.UIFigure.UserData.camY0   = -20.0;
    app.UIFigure.UserData.camZ0   = 4.7;

    app.UIFigure.UserData.pitch = 90;
    app.UIFigure.UserData.roll  = 0;
    app.UIFigure.UserData.yaw   = 0;

    app.UIFigure.UserData.ballName   = "tennisBall";
    app.UIFigure.UserData.sensorW_mm = 36;

    % temp folder for GUI images
    app.UIFigure.UserData.TempFolder = fullfile(tempdir, 'ESD_Lab4_GUI_Temp');
    if ~exist(app.UIFigure.UserData.TempFolder, 'dir')
        mkdir(app.UIFigure.UserData.TempFolder);
    end

    % -------------------- Connect to Blender once --------------------
    try
        app.UIFigure.UserData.client = tcpclient( ...
            app.UIFigure.UserData.server_ip, ...
            app.UIFigure.UserData.server_port, ...
            "Timeout", 20);

        fprintf(1, "Connected to Blender server\n");
    catch ME
        uialert(app.UIFigure, ...
            sprintf("Could not connect to Blender server:\n%s", ME.message), ...
            "Connection Error");
    end

    % -------------------- Clear displayed images --------------------
    app.LeftImage.ImageSource  = "";
    app.RightImage.ImageSource = "";

    % -------------------- Clear axes --------------------
    cla(app.DepthErrorUIAxes);
    cla(app.EstVsTrueDepthUIAxes);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
  % -------------------- Pull GUI inputs --------------------
    width  = app.ResolutionColumnsEditField.Value;
    height = app.ResolutionRowsEditField.Value;

    B      = app.BaselinemEditField.Value;         % meters
    f_mm   = app.FocalLengthmmEditField.Value;     % mm
    scale  = app.ScaleblenderEditField.Value;

    ballZ  = app.BallStartingHeightEditField.Value;
    startY = app.BallStartingDepthEditField.Value;
    endY   = app.BallEndingDepthEditField.Value;
    stepY  = app.IncrementEditField.Value;

    % -------------------- Pull fixed settings --------------------
    client     = app.UIFigure.UserData.client;
    camName    = app.UIFigure.UserData.camName;
    camX0      = app.UIFigure.UserData.camX0;
    camY0      = app.UIFigure.UserData.camY0;
    camZ0      = app.UIFigure.UserData.camZ0;
    pitch      = app.UIFigure.UserData.pitch;
    roll       = app.UIFigure.UserData.roll;
    yaw        = app.UIFigure.UserData.yaw;
    ballName   = app.UIFigure.UserData.ballName;
    sensorW_mm = app.UIFigure.UserData.sensorW_mm;
    tempFolder = app.UIFigure.UserData.TempFolder;

    ballX = camX0;

    % -------------------- Derived camera values --------------------
    f_px = (f_mm / sensorW_mm) * width;
    cx   = width / 2;
    cy   = height / 2;

    fprintf("\n---- Using stereo params ----\n");
    fprintf("B = %.3f m\n", B);
    fprintf("f = %.1f mm, sensorW = %.1f mm, f_px = %.1f px\n", f_mm, sensorW_mm, f_px);
    fprintf("Resolution = %dx%d, cx=%.1f cy=%.1f\n\n", width, height, cx, cy);

    % -------------------- Sweep setup --------------------
    ballY_vals = startY : stepY : endY;
    N = numel(ballY_vals);

    Y_est_all  = nan(N,1);   % estimated forward depth
    X_est_all  = nan(N,1);   % estimated horizontal position
    Z_est_all  = nan(N,1);   % estimated vertical position
    Depth_true = nan(N,1);   % true forward depth
    Depth_err  = nan(N,1);   % forward depth error

    disp_px = nan(N,1);
    ok = false(N,1);

    for i = 1:N
        ballY = ballY_vals(i);

        % 1) Move ball
        blenderLink(client, width, height, ballX, ballY, ballZ, 0, 0, 0, ballName);

        % 2) Render left/right images
        imgL = blenderLink(client, width, height, ...
            camX0 - B/2, camY0, camZ0, pitch, roll, yaw, camName);

        imgR = blenderLink(client, width, height, ...
            camX0 + B/2, camY0, camZ0, pitch, roll, yaw, camName);

        % 3) Detect ball centers
        [cLx, cLy] = detectBallCircle(app, imgL);
        [cRx, cRy] = detectBallCircle(app, imgR);

        if any(isnan([cLx cLy cRx cRy]))
            fprintf("ballY=%.2f -> detection FAILED\n", ballY);
            continue;
        end

        % 4) Horizontal disparity
        dx = (cLx - cRx);
        disp_px(i) = abs(dx);

        if disp_px(i) < 1
            fprintf("ballY=%.2f -> disparity too small (%.3f px)\n", ballY, disp_px(i));
            continue;
        end

        ok(i) = true;

        % 5) Estimated coordinates
        Y_est = scale * (B * f_px) / disp_px(i);           % forward depth
        X_est = ((cLx - cx) * Y_est) / f_px - B/2;         % horizontal
        Z_est = camZ0 - ((cLy - cy) * Y_est) / f_px;       % height

        Y_est_all(i) = Y_est;
        X_est_all(i) = X_est;
        Z_est_all(i) = Z_est;

        % 6) True values and error
        Depth_true(i) = ballY - camY0;
        Depth_err(i)  = abs(Y_est - Depth_true(i));

        fprintf("ballY=%.2f m, disparity=%.2f px, Depth_est=%.3f m, Depth_true=%.3f m, Error=%.3f m, X=%.3f m, Z=%.3f m\n", ...
            ballY, disp_px(i), Y_est, Depth_true(i), Depth_err(i), X_est, Z_est);

        % 7) Draw crosshair overlays and save to temp files for uiimage
        dispL = drawCrosshair(app, imgL, cLx, cLy);
        dispR = drawCrosshair(app, imgR, cRx, cRy);

        tmpL = fullfile(tempFolder, 'left_live.png');
        tmpR = fullfile(tempFolder, 'right_live.png');

        imwrite(dispL, tmpL);
        imwrite(dispR, tmpR);

        app.LeftImage.ImageSource  = tmpL;
        app.RightImage.ImageSource = tmpR;

        % 8) Update current point values
        app.XmEditField.Value            = X_est;
        app.ZmEditField.Value            = Z_est;
        app.BallYmEditField.Value        = ballY;
        app.DisppxEditField.Value        = disp_px(i);
        app.DepthEstmEditField.Value     = Y_est;
        app.DepthTruemEditField.Value    = Depth_true(i);
        app.DepthErrmEditField.Value     = Depth_err(i);

        drawnow;
    end

    % -------------------- Plot into GUI axes --------------------
    if ~any(ok)
        uialert(app.UIFigure, ...
            "No valid points. Check Blender camera setup, baseline, or ball visibility.", ...
            "Sweep Failed");
        cla(app.DepthErrorUIAxes);
        cla(app.EstVsTrueDepthUIAxes);
    else
        cla(app.DepthErrorUIAxes);
        plot(app.DepthErrorUIAxes, Depth_true(ok), Depth_err(ok), 'o-');
        grid(app.DepthErrorUIAxes, 'on');
        xlabel(app.DepthErrorUIAxes, 'True Depth (m)');
        ylabel(app.DepthErrorUIAxes, 'Absolute Error (m)');
        title(app.DepthErrorUIAxes, 'Depth Error vs True Depth');

        cla(app.EstVsTrueDepthUIAxes);
        plot(app.EstVsTrueDepthUIAxes, Depth_true(ok), Depth_true(ok), '-'); 
        hold(app.EstVsTrueDepthUIAxes, 'on');
        plot(app.EstVsTrueDepthUIAxes, Depth_true(ok), Y_est_all(ok), 'o');
        grid(app.EstVsTrueDepthUIAxes, 'on');
        xlabel(app.EstVsTrueDepthUIAxes, 'True Depth (m)');
        ylabel(app.EstVsTrueDepthUIAxes, 'Estimated Depth (m)');
        title(app.EstVsTrueDepthUIAxes, 'Estimated vs True Depth');
        legend(app.EstVsTrueDepthUIAxes, 'Ideal (y=x)', 'Measured', 'Location', 'best');
        hold(app.EstVsTrueDepthUIAxes, 'off');
    end

    % ----------------- Helper Functions ---------------

    function [x, y] = detectBallCircle(app, imgRGB)
    x = NaN; 
    y = NaN;

    gray = rgb2gray(imgRGB);
    gray = im2double(gray);
    gray = adapthisteq(gray);
    gray = imgaussfilt(gray, 1);

    [centers, ~, metric] = imfindcircles(gray, [10 40], ...
        'ObjectPolarity','bright', ...
        'Sensitivity', 0.98, ...
        'EdgeThreshold', 0.05);

    if isempty(centers)
        return;
    end

    [~, idx] = max(metric);
    x = centers(idx, 1);
    y = centers(idx, 2);
end

function out = drawCrosshair(app, img, x, y)
    out = img;

    if any(isnan([x y]))
        return;
    end

    x = round(x);
    y = round(y);

    [h,w,~] = size(out);
    L = 25;

    x1 = max(1, x-L); x2 = min(w, x+L);
    y1 = max(1, y-L); y2 = min(h, y+L);

    % horizontal line (red)
    out(y, x1:x2, 1) = 255;
    out(y, x1:x2, 2) = 0;
    out(y, x1:x2, 3) = 0;

    % vertical line (red)
    out(y1:y2, x, 1) = 255;
    out(y1:y2, x, 2) = 0;
    out(y1:y2, x, 3) = 0;
end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Very simple reset button.
            startupFcn(app);
            %RunButtonPushed(app, event); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EstVsTrueDepthUIAxes = uiaxes(app.UIFigure);
            title(app.EstVsTrueDepthUIAxes, 'Estimated vs True Depth')
            xlabel(app.EstVsTrueDepthUIAxes, 'X')
            ylabel(app.EstVsTrueDepthUIAxes, 'Y')
            zlabel(app.EstVsTrueDepthUIAxes, 'Z')
            app.DepthErrorUIAxes = uiaxes(app.UIFigure);
            title(app.DepthErrorUIAxes, 'Depth Error vs True Depth')
            xlabel(app.DepthErrorUIAxes, 'X')
            ylabel(app.DepthErrorUIAxes, 'Y')
            zlabel(app.DepthErrorUIAxes, 'Z')
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.Editable = 'off';
            app.TitleEditField.Value = 'STEREO BALL FINDER - WITH IMAGES FROM BLENDER';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.BaselinemEditFieldLabel = uilabel(app.InputParametersPanel);
            app.BaselinemEditFieldLabel.Text = 'Baseline (m):';
            app.BaselinemEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.BaselinemEditField.Limits = [0 5];
            app.FocalLengthmmEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length (mm):';
            app.FocalLengthmmEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.FocalLengthmmEditField.Limits = [0 50];
            app.ResolutionEditFieldLabel = uilabel(app.InputParametersPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution:';
            app.ResolutionColumnsEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ResolutionColumnsEditField.Limits = [0 Inf];
            app.ResolutionColumnsEditField.Value = 1080;
            app.ResolutionXLabel = uilabel(app.InputParametersPanel);
            app.ResolutionXLabel.Text = 'X';
            app.ResolutionRowsEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ResolutionRowsEditField.Limits = [0 Inf];
            app.ResolutionRowsEditField.Value = 720;
            app.BallStartingHeightEditFieldLabel = uilabel(app.InputParametersPanel);
            app.BallStartingHeightEditFieldLabel.Text = 'Ball Starting Height';
            app.BallStartingHeightEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.BallStartingDepthEditFieldLabel = uilabel(app.InputParametersPanel);
            app.BallStartingDepthEditFieldLabel.Text = 'Ball Starting Depth';
            app.BallStartingDepthEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.BallEndingDepthEditFieldLabel = uilabel(app.InputParametersPanel);
            app.BallEndingDepthEditFieldLabel.Text = 'Ball Ending Depth';
            app.BallEndingDepthEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.IncrementEditFieldLabel = uilabel(app.InputParametersPanel);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ScaleblenderEditFieldLabel = uilabel(app.InputParametersPanel);
            app.ScaleblenderEditFieldLabel.Text = 'Scale (blender)';
            app.ScaleblenderEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.CameraViewPanel = uipanel(app.UIFigure);
            app.CameraViewPanel.Title = 'Camera View';
            app.LeftImage = uiimage(app.CameraViewPanel);
            app.RightImage = uiimage(app.CameraViewPanel);
            app.LeftImageLabel = uilabel(app.CameraViewPanel);
            app.LeftImageLabel.Text = 'Left Image';
            app.RightImageLabel = uilabel(app.CameraViewPanel);
            app.RightImageLabel.Text = 'Right Image';
            app.CurrentPointValuesPanel = uipanel(app.UIFigure);
            app.CurrentPointValuesPanel.Title = 'Current Point Values';
            app.XmEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.XmEditField.Editable = 'off';
            app.ZmEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.ZmEditFieldLabel.Text = 'Z (m)';
            app.ZmEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.ZmEditField.Editable = 'off';
            app.DisppxEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.DisppxEditFieldLabel.Text = 'Disp (px)';
            app.DisppxEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.DisppxEditField.Editable = 'off';
            app.DepthEstmEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.DepthEstmEditFieldLabel.Text = 'Depth Est. (m)';
            app.DepthEstmEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.DepthEstmEditField.Editable = 'off';
            app.DepthTruemEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.DepthTruemEditFieldLabel.Text = 'Depth True (m)';
            app.DepthTruemEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.DepthTruemEditField.Editable = 'off';
            app.DepthErrmEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.DepthErrmEditFieldLabel.Text = 'Depth Err (m)';
            app.DepthErrmEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.DepthErrmEditField.Editable = 'off';
            app.BallYmEditFieldLabel = uilabel(app.CurrentPointValuesPanel);
            app.BallYmEditFieldLabel.Text = 'Ball Y (m)';
            app.BallYmEditField = uieditfield(app.CurrentPointValuesPanel, 'numeric');
            app.BallYmEditField.Editable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESD_Lab4_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end